classdef ImageProcessingProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageProcessingProject          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Phase12Tab                      matlab.ui.container.Tab
        NoisyImage1SaltPepperPanel      matlab.ui.container.Panel
        ErrorRatioEditFieldLabel        matlab.ui.control.Label
        ErrorRatioEditField             matlab.ui.control.NumericEditField
        MakeSaltPepperNoiseButton       matlab.ui.control.Button
        SaveImage1Button                matlab.ui.control.Button
        UIAxes_NoisyImage1              matlab.ui.control.UIAxes
        UIAxes_imnoise                  matlab.ui.control.UIAxes
        NoisyImage2GaussianPanel        matlab.ui.container.Panel
        MakeGaussianNoiseButton         matlab.ui.control.Button
        UIAxes_NoisyImage2              matlab.ui.control.UIAxes
        UIAxes_imnoise_2                matlab.ui.control.UIAxes
        ErrorRatioEditField_2Label      matlab.ui.control.Label
        ErrorRatioEditField_2           matlab.ui.control.NumericEditField
        SaveImage2Button                matlab.ui.control.Button
        OriginalImagePanel              matlab.ui.container.Panel
        UIAxes_Gray_Image               matlab.ui.control.UIAxes
        UIAxes_Original_Image           matlab.ui.control.UIAxes
        BrowseImageButton               matlab.ui.control.Button
        GenerateSaltandPepperANDGaussianNoisestoImageLabel  matlab.ui.control.Label
        Phase3Tab                       matlab.ui.container.Tab
        CutOffValueEditFieldLabel       matlab.ui.control.Label
        CutOffValueEditField            matlab.ui.control.NumericEditField
        ProcessingPanel                 matlab.ui.container.Panel
        UIAxes_Fourier_Transform_Image  matlab.ui.control.UIAxes
        UIAxes_Applying_Filter          matlab.ui.control.UIAxes
        ApplyFilterButton               matlab.ui.control.Button
        SaveImage3Button                matlab.ui.control.Button
        OriginalImagePanel_4            matlab.ui.container.Panel
        UIAxes_Gray_Image_2             matlab.ui.control.UIAxes
        UIAxes_Original_Image_2         matlab.ui.control.UIAxes
        BrowseImageButton_2             matlab.ui.control.Button
        UIAxes_Back_to_Spacial          matlab.ui.control.UIAxes
        ApplyLowPassFilterLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        originalImage;
        grayImage;
        noisyImage_task1;
        noisyImage_task2;
        
        originalImage2;
        grayImage2;
        fftImage;
        applyFilterImage;
        %filteredImage;
    end

    methods (Access = public)
    
        function noisyImage = MakeSaltPepperNoise(app, InputImage, ErrorRatio)
            noisyImage = InputImage;
            F = round(rand());
            N = round(ErrorRatio*numel(InputImage)); 
            R = randi([0 numel(InputImage)],N,1);

            for i = 1:length(R)
                if(F == 0)
                    try
                        noisyImage(R(i)) = 0;        %Generates Black Pixel
                        
                    catch
                        return;
                    end
                else
                    try
                        noisyImage(R(i)) = 255;      %Generates White Pixel
                        
                    catch
                        return;
                    end
                end
                F = round(rand());
            end
            
            noisyImage = uint8(noisyImage);
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function noisyImage = MakeGaussianNoise(app, InputImage, ErrorRatio)
            ErrorRatio = ErrorRatio * 100;
            noisyImage = double(InputImage);
            randomValues = randn(size(InputImage));
            valuesMULerror = randomValues * ErrorRatio;
            
            noisyImage =  noisyImage + valuesMULerror;
            noisyImage = uint8(noisyImage);
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
function filteredImage = ApplyFilter(app, InputImage, CutOffValue)
            [NoOfRows, NoOfColums] = size(InputImage);
            f = fft2(InputImage);
            F = fftshift(log(abs(f)));
            H = zeros(size(InputImage));                     
            CutOffValue = 50;

            for i = 1 : NoOfRows
                for j = 1 : NoOfColums
                    if((i-NoOfRows/2)^2 + (j-NoOfColums/2)^2 < CutOffValue^2)
                        H(i, j) = 1;                    
                    end
                end
            end
            
            G = H .* F;                                 
            filteredImage = real(ifft2(G));
            
            app.fftImage = F;
            app.applyFilterImage = G;
        end
        
    end

    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg;*.tif;*.png;'},'Select an image');
            if ~isequal(fileName,0)
                app.originalImage = imread(strcat(pathName, fileName));%imread('C:\image.jpg');
                imshow(app.originalImage, 'Parent', app.UIAxes_Original_Image);
    
                app.grayImage = rgb2gray(app.originalImage);
                imshow(app.grayImage, 'Parent', app.UIAxes_Gray_Image);
                
                app.MakeSaltPepperNoiseButton.Enable = 'on';
                app.MakeGaussianNoiseButton.Enable = 'on';
            end 
        end

        % Button pushed function: MakeGaussianNoiseButton
        function MakeGaussianNoiseButtonPushed(app, event)
            app.noisyImage_task2 = MakeGaussianNoise(app, app.grayImage, app.ErrorRatioEditField_2.Value);
            imshow(app.noisyImage_task2, 'Parent', app.UIAxes_NoisyImage2);
            
            noisyImage_imnoise2 = imnoise(app.grayImage,'gaussian',0.05);
            imshow(noisyImage_imnoise2, 'Parent', app.UIAxes_imnoise_2);
            
            app.SaveImage2Button.Enable = 'on';
        end

        % Button pushed function: SaveImage1Button
        function SaveImage1ButtonPushed(app, event)
            [fileName, pathName] = uiputfile('*.jpg','Save image');
            if ~isequal(fileName,0) && ~isequal(pathName,0)
                imwrite(app.noisyImage_task1,strcat(pathName,fileName));
            end
        end

        % Button pushed function: SaveImage2Button
        function SaveImage2ButtonPushed(app, event)
            [fileName, pathName] = uiputfile('*.jpg','Save image');
            if ~isequal(fileName,0) && ~isequal(pathName,0)
                imwrite(app.noisyImage_task2,strcat(pathName,fileName));
            end
        end

        % Button pushed function: BrowseImageButton_2
        function BrowseImageButton_2Pushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg;*.tif;*.png;'},'Select an image');
            if ~isequal(fileName,0)
                app.originalImage2 = imread(strcat(pathName, fileName));
                imshow(app.originalImage2, 'Parent', app.UIAxes_Original_Image_2);
    
                app.grayImage2 = rgb2gray(app.originalImage2);
                imshow(app.grayImage2, 'Parent', app.UIAxes_Gray_Image_2);
                
                app.ApplyFilterButton.Enable = 'on';
            end 
        end

        % Button pushed function: MakeSaltPepperNoiseButton
        function MakeSaltPepperNoiseButtonPushed(app, event)
            app.noisyImage_task1 = MakeSaltPepperNoise(app, app.grayImage, app.ErrorRatioEditField.Value);
            imshow(app.noisyImage_task1, 'Parent', app.UIAxes_NoisyImage1);
            
            noisyImage_imnoise1 = imnoise(app.grayImage,'salt & pepper',app.ErrorRatioEditField.Value);
            imshow(noisyImage_imnoise1, 'Parent', app.UIAxes_imnoise);
            
            app.SaveImage1Button.Enable = 'on';
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            filteredImage = ApplyFilter(app, app.grayImage2, app.CutOffValueEditField.Value);  
            
            imshow(app.fftImage, 'Parent', app.UIAxes_Fourier_Transform_Image);
            imshow(app.applyFilterImage, 'Parent', app.UIAxes_Applying_Filter);
            imshow(filteredImage, 'Parent', app.UIAxes_Back_to_Spacial);
            
            app.SaveImage3Button.Enable = 'on';
        end

        % Button pushed function: SaveImage3Button
        function SaveImage3ButtonPushed(app, event)
            [fileName, pathName] = uiputfile('*.jpg','Save image');
            if ~isequal(fileName,0) && ~isequal(pathName,0)
                %imwrite(app.filteredImage,strcat(pathName,fileName));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageProcessingProject = uifigure;
            app.ImageProcessingProject.Name = 'Image Processing Project';
            setAutoResize(app, app.ImageProcessingProject, true)
            app.TabGroup = uitabgroup(app.ImageProcessingProject);
            app.Phase12Tab = uitab(app.TabGroup);
            app.Phase12Tab.Title = 'Phase 1 & 2';
            app.Phase12Tab.Units = 'pixels';
            app.NoisyImage1SaltPepperPanel = uipanel(app.Phase12Tab);
            app.NoisyImage1SaltPepperPanel.Title = 'Noisy Image 1 [Salt & Pepper]';
            app.ErrorRatioEditFieldLabel = uilabel(app.NoisyImage1SaltPepperPanel);
            app.ErrorRatioEditFieldLabel.Text = 'Error Ratio :';
            app.ErrorRatioEditField = uieditfield(app.NoisyImage1SaltPepperPanel, 'numeric');
            app.ErrorRatioEditField.Limits = [0 1];
            app.ErrorRatioEditField.Value = 0.05;
            app.MakeSaltPepperNoiseButton = uibutton(app.NoisyImage1SaltPepperPanel, 'push');
            app.MakeSaltPepperNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @MakeSaltPepperNoiseButtonPushed, true);
            app.MakeSaltPepperNoiseButton.Enable = 'off';
            app.MakeSaltPepperNoiseButton.Text = 'Make Salt & Pepper Noise';
            app.SaveImage1Button = uibutton(app.NoisyImage1SaltPepperPanel, 'push');
            app.SaveImage1Button.ButtonPushedFcn = createCallbackFcn(app, @SaveImage1ButtonPushed, true);
            app.SaveImage1Button.Enable = 'off';
            app.SaveImage1Button.Text = 'Save Image 1';
            app.UIAxes_NoisyImage1 = uiaxes(app.NoisyImage1SaltPepperPanel);
            title(app.UIAxes_NoisyImage1, 'Task');
            app.UIAxes_NoisyImage1.XTick = zeros(1,0);
            app.UIAxes_NoisyImage1.XTickLabel = {};
            app.UIAxes_NoisyImage1.YTick = zeros(1,0);
            app.UIAxes_NoisyImage1.YTickLabel = {};
            app.UIAxes_imnoise = uiaxes(app.NoisyImage1SaltPepperPanel);
            title(app.UIAxes_imnoise, 'imnoise() FUNC');
            app.UIAxes_imnoise.XTick = zeros(1,0);
            app.UIAxes_imnoise.XTickLabel = {};
            app.UIAxes_imnoise.YTick = zeros(1,0);
            app.UIAxes_imnoise.YTickLabel = {};
            app.NoisyImage2GaussianPanel = uipanel(app.Phase12Tab);
            app.NoisyImage2GaussianPanel.Title = 'Noisy Image 2 [Gaussian]';
            app.MakeGaussianNoiseButton = uibutton(app.NoisyImage2GaussianPanel, 'push');
            app.MakeGaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @MakeGaussianNoiseButtonPushed, true);
            app.MakeGaussianNoiseButton.Enable = 'off';
            app.MakeGaussianNoiseButton.Text = 'Make Gaussian Noise';
            app.UIAxes_NoisyImage2 = uiaxes(app.NoisyImage2GaussianPanel);
            title(app.UIAxes_NoisyImage2, 'Task');
            app.UIAxes_NoisyImage2.XTick = zeros(1,0);
            app.UIAxes_NoisyImage2.XTickLabel = {};
            app.UIAxes_NoisyImage2.YTick = zeros(1,0);
            app.UIAxes_NoisyImage2.YTickLabel = {};
            app.UIAxes_imnoise_2 = uiaxes(app.NoisyImage2GaussianPanel);
            title(app.UIAxes_imnoise_2, 'imnoise() FUNC');
            app.UIAxes_imnoise_2.XTick = zeros(1,0);
            app.UIAxes_imnoise_2.XTickLabel = {};
            app.UIAxes_imnoise_2.YTick = zeros(1,0);
            app.UIAxes_imnoise_2.YTickLabel = {};
            app.ErrorRatioEditField_2Label = uilabel(app.NoisyImage2GaussianPanel);
            app.ErrorRatioEditField_2Label.Text = 'Error Ratio :';
            app.ErrorRatioEditField_2 = uieditfield(app.NoisyImage2GaussianPanel, 'numeric');
            app.ErrorRatioEditField_2.Limits = [0 1];
            app.ErrorRatioEditField_2.Value = 0.5;
            app.SaveImage2Button = uibutton(app.NoisyImage2GaussianPanel, 'push');
            app.SaveImage2Button.ButtonPushedFcn = createCallbackFcn(app, @SaveImage2ButtonPushed, true);
            app.SaveImage2Button.Enable = 'off';
            app.SaveImage2Button.Text = 'Save Image 2';
            app.OriginalImagePanel = uipanel(app.Phase12Tab);
            app.OriginalImagePanel.Title = 'Original Image';
            app.UIAxes_Gray_Image = uiaxes(app.OriginalImagePanel);
            title(app.UIAxes_Gray_Image, 'Gray Image');
            app.UIAxes_Gray_Image.XTick = zeros(1,0);
            app.UIAxes_Gray_Image.XTickLabel = {};
            app.UIAxes_Gray_Image.YTick = zeros(1,0);
            app.UIAxes_Gray_Image.YTickLabel = {};
            app.UIAxes_Original_Image = uiaxes(app.OriginalImagePanel);
            title(app.UIAxes_Original_Image, 'Original Image');
            app.UIAxes_Original_Image.XTick = zeros(1,0);
            app.UIAxes_Original_Image.XTickLabel = {};
            app.UIAxes_Original_Image.YTick = zeros(1,0);
            app.UIAxes_Original_Image.YTickLabel = {};
            app.BrowseImageButton = uibutton(app.OriginalImagePanel, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.GenerateSaltandPepperANDGaussianNoisestoImageLabel = uilabel(app.Phase12Tab);
            app.GenerateSaltandPepperANDGaussianNoisestoImageLabel.Text = 'Generate Salt and Pepper AND Gaussian Noises to Image';
            app.Phase3Tab = uitab(app.TabGroup);
            app.Phase3Tab.Title = 'Phase 3';
            app.Phase3Tab.Units = 'pixels';
            app.CutOffValueEditFieldLabel = uilabel(app.Phase3Tab);
            app.CutOffValueEditFieldLabel.Text = 'Cut-Off Value :';
            app.CutOffValueEditField = uieditfield(app.Phase3Tab, 'numeric');
            app.CutOffValueEditField.Limits = [0 100];
            app.CutOffValueEditField.Value = 50;
            app.ProcessingPanel = uipanel(app.Phase3Tab);
            app.ProcessingPanel.Title = 'Processing';
            app.UIAxes_Fourier_Transform_Image = uiaxes(app.ProcessingPanel);
            title(app.UIAxes_Fourier_Transform_Image, 'Fourier Transform Image');
            app.UIAxes_Fourier_Transform_Image.XTick = zeros(1,0);
            app.UIAxes_Fourier_Transform_Image.XTickLabel = {};
            app.UIAxes_Fourier_Transform_Image.YTick = zeros(1,0);
            app.UIAxes_Fourier_Transform_Image.YTickLabel = {};
            app.UIAxes_Applying_Filter = uiaxes(app.ProcessingPanel);
            title(app.UIAxes_Applying_Filter, 'Applying Filter');
            app.UIAxes_Applying_Filter.XTick = zeros(1,0);
            app.UIAxes_Applying_Filter.XTickLabel = {};
            app.UIAxes_Applying_Filter.YTick = zeros(1,0);
            app.UIAxes_Applying_Filter.YTickLabel = {};
            app.ApplyFilterButton = uibutton(app.Phase3Tab, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Enable = 'off';
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.SaveImage3Button = uibutton(app.Phase3Tab, 'push');
            app.SaveImage3Button.ButtonPushedFcn = createCallbackFcn(app, @SaveImage3ButtonPushed, true);
            app.SaveImage3Button.Enable = 'off';
            app.SaveImage3Button.Text = 'Save Image 3';
            app.OriginalImagePanel_4 = uipanel(app.Phase3Tab);
            app.OriginalImagePanel_4.Title = 'Original Image';
            app.UIAxes_Gray_Image_2 = uiaxes(app.OriginalImagePanel_4);
            title(app.UIAxes_Gray_Image_2, 'Gray Image');
            app.UIAxes_Gray_Image_2.XTick = zeros(1,0);
            app.UIAxes_Gray_Image_2.XTickLabel = {};
            app.UIAxes_Gray_Image_2.YTick = zeros(1,0);
            app.UIAxes_Gray_Image_2.YTickLabel = {};
            app.UIAxes_Original_Image_2 = uiaxes(app.OriginalImagePanel_4);
            title(app.UIAxes_Original_Image_2, 'Original Image');
            app.UIAxes_Original_Image_2.XTick = zeros(1,0);
            app.UIAxes_Original_Image_2.XTickLabel = {};
            app.UIAxes_Original_Image_2.YTick = zeros(1,0);
            app.UIAxes_Original_Image_2.YTickLabel = {};
            app.BrowseImageButton_2 = uibutton(app.OriginalImagePanel_4, 'push');
            app.BrowseImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButton_2Pushed, true);
            app.BrowseImageButton_2.Text = 'Browse Image';
            app.UIAxes_Back_to_Spacial = uiaxes(app.Phase3Tab);
            title(app.UIAxes_Back_to_Spacial, 'Back to Spacial');
            app.UIAxes_Back_to_Spacial.XTick = zeros(1,0);
            app.UIAxes_Back_to_Spacial.XTickLabel = {};
            app.UIAxes_Back_to_Spacial.YTick = zeros(1,0);
            app.UIAxes_Back_to_Spacial.YTickLabel = {};
            app.ApplyLowPassFilterLabel = uilabel(app.Phase3Tab);
            app.ApplyLowPassFilterLabel.Text = 'Apply Low Pass Filter';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageProcessingProjectGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageProcessingProject)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageProcessingProject)
        end
    end
end